classdef ecgScorer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ecgScorerV10UIFigure           matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        ECGQualityEvaluationTab        matlab.ui.container.Tab
        TextArea                       matlab.ui.control.TextArea
        EXPORTPanel                    matlab.ui.container.Panel
        exportxlsButton                matlab.ui.control.Button
        exportcsvButton                matlab.ui.control.Button
        FilenameEditField              matlab.ui.control.EditField
        FilenameEditFieldLabel         matlab.ui.control.Label
        ECGQualityPanel                matlab.ui.container.Panel
        ecgScoreButton                 matlab.ui.control.Button
        PLOTPanel                      matlab.ui.container.Panel
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        DATAPanel                      matlab.ui.container.Panel
        PlotECGButton                  matlab.ui.control.Button
        SamplingFrequencyHzEditField   matlab.ui.control.NumericEditField
        SamplingFrequencyHzEditFieldLabel  matlab.ui.control.Label
        LoadDataButton                 matlab.ui.control.Button
        SQIExtractionTab               matlab.ui.container.Tab
        QRScomplexDetectorsbasedSQIsPanel  matlab.ui.container.Panel
        SelectQRSSQIsDropDown          matlab.ui.control.DropDown
        SelectQRSSQIsDropDownLabel     matlab.ui.control.Label
        FrequencyDomainBasedSQIsPanel  matlab.ui.container.Panel
        SelectFrequencySQIsDropDown    matlab.ui.control.DropDown
        SelectFrequencySQIsDropDownLabel  matlab.ui.control.Label
        COMPUTEEXPORTPanel             matlab.ui.container.Panel
        ComputeSelectedSQIsButton      matlab.ui.control.Button
        exportxlsButton_2              matlab.ui.control.Button
        exportcsvButton_2              matlab.ui.control.Button
        FilenameEditField_2            matlab.ui.control.EditField
        FilenameEditField_2Label       matlab.ui.control.Label
        NonLinearSQIsPanel             matlab.ui.container.Panel
        SelectNonlinearSQIsDropDown    matlab.ui.control.DropDown
        SelectNonlinearSQIsDropDownLabel  matlab.ui.control.Label
        StatisticsSQIsPanel            matlab.ui.container.Panel
        SelectStatisticSQIsDropDown    matlab.ui.control.DropDown
        SelectStatisticSQIsDropDownLabel  matlab.ui.control.Label
        DATAPanel_2                    matlab.ui.container.Panel
        SamplingFrequencyHzEditField_sqi  matlab.ui.control.NumericEditField
        SamplingFrequencyHzEditField_2Label  matlab.ui.control.Label
        LoadDataButton_2               matlab.ui.control.Button
        TextArea_sqi                   matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        ecg % loaded ECG data
        sampFreq % sampling frequency of the database
    end
    
    properties (Access = public)
        repport % quality report for the database
        statisticSQI
        freqSQI
        nonLin
        qrsSQI
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ecgScorerV10UIFigure.Icon = 'ecgScorerIcon.png';
            imshow("ecgScorer.png",'Parent',app.UIAxes);
            app.TextArea.Value = compose(['Welcome To ecgScorer,' ...
                '\n',' You can Load your Data']);
            app.SamplingFrequencyHzEditField.Value = 250; % assign Fs Default value
            app.sampFreq = app.SamplingFrequencyHzEditField.Value;
        end

        % Button pushed function: LoadDataButton, LoadDataButton_2
        function LoadDataButtonPushed(app, event)
            [FileName,PathName] = uigetfile({'*.txt;*.dat;*.csv;*.xls'}, ...
                'Select the Dataset');
            % open the file
            data = readmatrix([PathName,FileName]);
            app.ecg = data;

            imshow("dataLoad.png",'Parent',app.UIAxes);
            app.TextArea.Value = compose(['Data loaded Successfully,' ...
                '\n','\n',' Specify the Sampling Frequency']);
            app.TextArea_sqi.Value = compose(['Data loaded Successfully,' ...
                '\n','\n',' Specify the Sampling Frequency']);
        end

        % Value changed function: SamplingFrequencyHzEditField
        function SamplingFrequencyHzEditFieldValueChanged(app, event)
            app.sampFreq = app.SamplingFrequencyHzEditField.Value;
        end

        % Button pushed function: PlotECGButton
        function PlotECGButtonPushed(app, event)
            ecg1 = app.ecg(:,1);
            plot(app.UIAxes2,ecg1)
        end

        % Button pushed function: ecgScoreButton
        function ecgScoreButtonPushed(app, event)
            imshow('computing.png', 'Parent',app.UIAxes)
            app.TextArea.Value = compose(['**********************' ...
                '\n',' Computing ECG Quality Score', '\n', ...
                '*********************,']);
            % calculation of ecgScore
            [class, comment, signalNumber, aScore, iScore] = scorer12(app.ecg, app.sampFreq);
            T = table(signalNumber, class, comment, aScore, iScore);
            app.repport = T;
            assignin("base",'repport',class)

            imshow('completed.png', 'Parent',app.UIAxes)
            app.TextArea.Value = compose(['**********************' ...
                '\n','Calculation Completed', '\n', ...
                'You Can Export the Result,']);
        end

        % Button pushed function: exportcsvButton
        function exportcsvButtonPushed(app, event)
            fileName = app.FilenameEditField.Value;
            savDir = uigetdir;
            writetable(app.repport,[savDir, '\', fileName, '.csv'])

            app.TextArea.Value = compose(['**********************' ...
                '\n','Result Exported Successfully', '\n', ...
                '*********************,']);
        end

        % Button pushed function: exportxlsButton
        function exportxlsButtonPushed(app, event)
            fileName = app.FilenameEditField.Value;
            savDir = uigetdir;
            writetable(app.repport,[savDir, '\', fileName, '.xls'])

            app.TextArea.Value = compose(['**********************' ...
                '\n','Result Exported Successfully', '\n', ...
                '*********************,']);
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Button pushed function: ComputeSelectedSQIsButton
        function ComputeSelectedSQIsButtonPushed(app, event)
            app.TextArea_sqi.Value = " ";
            app.TextArea_sqi.Value = compose(['Computing All,' ...
                '\n',' The Specified the Signal Quality Indices']);
            disp(compose(['Computing All,' ...
                '\n',' The Specified the Signal Quality Indices']));

            if strcmp(app.SelectStatisticSQIsDropDown.Value,'none')
                app.statisticSQI = table([]);
            else
                disp("Computing Statistics SQIs")
                app.statisticSQI = statSQI(app.ecg,app.SelectStatisticSQIsDropDown.Value);
            end

            if strcmp(app.SelectFrequencySQIsDropDown.Value,'none')
                app.freqSQI = table();
            else
                disp("Computing Frequency SQIs")
                app.freqSQI = frequencySQI(app.ecg, ...
                    app.SelectFrequencySQIsDropDown.Value, ...
                    app.SamplingFrequencyHzEditField_sqi.Value);
            end 

            if strcmp(app.SelectNonlinearSQIsDropDown.Value,'none')
                app.nonLin = table();
            else
                disp("Computing non-linear SQIs")
                app.nonLin = nonLinearSQI(app.ecg, ...
                    app.SelectNonlinearSQIsDropDown.Value);
            end

            if strcmp(app.SelectQRSSQIsDropDown.Value,'none')
                app.qrsSQI = table();
            else
                disp("Computing QRS-detectors based SQIs")
                app.qrsSQI = qrsDetectorSQI(app.ecg, ...
                    app.SelectQRSSQIsDropDown.Value, ...
                    app.SamplingFrequencyHzEditField_sqi.Value);
            end

            
            assignin("base",'statistiSQI',app.statisticSQI)
            assignin("base", 'freqSQI', app.freqSQI)
            assignin("base","nonLinSQI", app.nonLin) 
            assignin("base", 'qrsSQI', app.qrsSQI) 

            app.TextArea_sqi.Value = compose(['Computation Successfully Completed All,' ...
                '\n', '**************************']);

        end

        % Button pushed function: exportcsvButton_2
        function exportcsvButton_2Pushed(app, event)
            fileName = app.FilenameEditField_2.Value;
            savDir = uigetdir;

            if ~isempty(app.statisticSQI)
                writetable(app.statisticSQI,[savDir, '\', fileName, '_Stat.csv'])
            end
            if ~isempty(app.freqSQI)
                writetable(app.freqSQI,[savDir, '\', fileName, '_Freq.csv'])
            end
            if ~isempty(app.nonLin)
                writetable(app.nonLin,[savDir, '\', fileName, '_NonLin.csv'])
            end
            if ~isempty(app.qrsSQI)
                writetable(app.qrsSQI,[savDir, '\', fileName, '_qrsDet.csv'])
            end

            app.TextArea_sqi.Value = compose(['**********************' ...
                '\n','Result Exported Successfully', '\n', ...
                '*********************,']);
        end

        % Button pushed function: exportxlsButton_2
        function exportxlsButton_2Pushed(app, event)
            fileName = app.FilenameEditField_2.Value;
            savDir = uigetdir;
            if ~isempty(app.statisticSQI)
                writetable(app.statisticSQI,[savDir, '\', fileName, '.xls'] ...
                    ,'Sheet','Statistics SQI')
            end
            if ~isempty(app.freqSQI)
                writetable(app.freqSQI,[savDir, '\', fileName, '.xls'], ...
                    'Sheet','Frequency SQI')
            end
            if ~isempty(app.nonLin)
                writetable(app.nonLin,[savDir, '\', fileName, '.xls'], ...
                    'Sheet','Non linear SQI')
            end
            if ~isempty(app.qrsSQI)
                writetable(app.qrsSQI,[savDir, '\', fileName, '.xls'], ...
                    'Sheet','qrs detectors SQI')
            end

            app.TextArea_sqi.Value = compose(['**********************' ...
                '\n','Result Exported Successfully', '\n', ...
                '*********************,']);
        end

        % Button down function: SQIExtractionTab
        function SQIExtractionTabButtonDown(app, event)
            app.TextArea_sqi.Value = compose(['**********************' ...
                '\n','Signal Quality Indices Extraction', '\n', ...
                '*********************']);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ecgScorerV10UIFigure = uifigure('Visible', 'off');
            app.ecgScorerV10UIFigure.Name = 'ecgScorer V 1.0';
            app.TabGroup = uitabgroup(app.ecgScorerV10UIFigure);
            app.ECGQualityEvaluationTab = uitab(app.TabGroup);
            app.ECGQualityEvaluationTab.Title = 'ECG Quality Evaluation';
            app.DATAPanel = uipanel(app.ECGQualityEvaluationTab);
            app.DATAPanel.Title = 'DATA';
            app.LoadDataButton = uibutton(app.DATAPanel, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.SamplingFrequencyHzEditFieldLabel = uilabel(app.DATAPanel);
            app.SamplingFrequencyHzEditFieldLabel.Text = 'Sampling Frequency (Hz)';
            app.SamplingFrequencyHzEditField = uieditfield(app.DATAPanel, 'numeric');
            app.SamplingFrequencyHzEditField.ValueChangedFcn = createCallbackFcn(app, @SamplingFrequencyHzEditFieldValueChanged, true);
            app.PlotECGButton = uibutton(app.DATAPanel, 'push');
            app.PlotECGButton.ButtonPushedFcn = createCallbackFcn(app, @PlotECGButtonPushed, true);
            app.PlotECGButton.Text = 'Plot ECG';
            app.PLOTPanel = uipanel(app.ECGQualityEvaluationTab);
            app.PLOTPanel.Title = 'PLOT';
            app.UIAxes2 = uiaxes(app.PLOTPanel);
            xlabel(app.UIAxes2, 'Samples')
            ylabel(app.UIAxes2, 'Amplitude')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.YTick = [0 0.4 0.8 1];
            app.UIAxes = uiaxes(app.PLOTPanel);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.ECGQualityPanel = uipanel(app.ECGQualityEvaluationTab);
            app.ECGQualityPanel.Title = 'ECG Quality';
            app.ecgScoreButton = uibutton(app.ECGQualityPanel, 'push');
            app.ecgScoreButton.ButtonPushedFcn = createCallbackFcn(app, @ecgScoreButtonPushed, true);
            app.ecgScoreButton.Text = 'ecgScore';
            app.EXPORTPanel = uipanel(app.ECGQualityEvaluationTab);
            app.EXPORTPanel.Title = 'EXPORT';
            app.FilenameEditFieldLabel = uilabel(app.EXPORTPanel);
            app.FilenameEditFieldLabel.Text = 'Filename';
            app.FilenameEditField = uieditfield(app.EXPORTPanel, 'text');
            app.FilenameEditField.Value = 'report';
            app.exportcsvButton = uibutton(app.EXPORTPanel, 'push');
            app.exportcsvButton.ButtonPushedFcn = createCallbackFcn(app, @exportcsvButtonPushed, true);
            app.exportcsvButton.Text = 'export .csv';
            app.exportxlsButton = uibutton(app.EXPORTPanel, 'push');
            app.exportxlsButton.ButtonPushedFcn = createCallbackFcn(app, @exportxlsButtonPushed, true);
            app.exportxlsButton.Text = 'export .xls';
            app.TextArea = uitextarea(app.ECGQualityEvaluationTab);
            app.TextArea.Editable = 'off';
            app.SQIExtractionTab = uitab(app.TabGroup);
            app.SQIExtractionTab.Title = 'SQI Extraction';
            app.SQIExtractionTab.ButtonDownFcn = createCallbackFcn(app, @SQIExtractionTabButtonDown, true);
            app.TextArea_sqi = uitextarea(app.SQIExtractionTab);
            app.TextArea_sqi.Editable = 'off';
            app.DATAPanel_2 = uipanel(app.SQIExtractionTab);
            app.DATAPanel_2.Title = 'DATA';
            app.LoadDataButton_2 = uibutton(app.DATAPanel_2, 'push');
            app.LoadDataButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton_2.Text = 'Load Data';
            app.SamplingFrequencyHzEditField_2Label = uilabel(app.DATAPanel_2);
            app.SamplingFrequencyHzEditField_2Label.Text = 'Sampling Frequency (Hz)';
            app.SamplingFrequencyHzEditField_sqi = uieditfield(app.DATAPanel_2, 'numeric');
            app.StatisticsSQIsPanel = uipanel(app.SQIExtractionTab);
            app.StatisticsSQIsPanel.Title = 'Statistics SQIs';
            app.SelectStatisticSQIsDropDownLabel = uilabel(app.StatisticsSQIsPanel);
            app.SelectStatisticSQIsDropDownLabel.Text = 'Select Statistic  SQIs ';
            app.SelectStatisticSQIsDropDown = uidropdown(app.StatisticsSQIsPanel);
            app.SelectStatisticSQIsDropDown.Items = {'none', 'all', 'des', 'HOS', 'min', 'mean', 'max', 'std', 'var', 'max-min', 'max-mean', 'MAC', 'LSAM', 'ASC', 'ST', 'AE', 'nzc', 'skn', 'kur', 'hosSQI'};
            app.SelectStatisticSQIsDropDown.Value = 'all';
            app.NonLinearSQIsPanel = uipanel(app.SQIExtractionTab);
            app.NonLinearSQIsPanel.Title = 'Non-Linear SQIs';
            app.SelectNonlinearSQIsDropDownLabel = uilabel(app.NonLinearSQIsPanel);
            app.SelectNonlinearSQIsDropDownLabel.Text = 'Select  Non-linear SQIs ';
            app.SelectNonlinearSQIsDropDown = uidropdown(app.NonLinearSQIsPanel);
            app.SelectNonlinearSQIsDropDown.Items = {'none', 'all', 'app', 'samp', 'fuz', 'fuzM', 'lzc', 'elz', 'h'};
            app.SelectNonlinearSQIsDropDown.Value = 'all';
            app.COMPUTEEXPORTPanel = uipanel(app.SQIExtractionTab);
            app.COMPUTEEXPORTPanel.Title = 'COMPUTE & EXPORT';
            app.FilenameEditField_2Label = uilabel(app.COMPUTEEXPORTPanel);
            app.FilenameEditField_2Label.Text = 'Filename';
            app.FilenameEditField_2 = uieditfield(app.COMPUTEEXPORTPanel, 'text');
            app.FilenameEditField_2.Value = 'extracted SQIs';
            app.exportcsvButton_2 = uibutton(app.COMPUTEEXPORTPanel, 'push');
            app.exportcsvButton_2.ButtonPushedFcn = createCallbackFcn(app, @exportcsvButton_2Pushed, true);
            app.exportcsvButton_2.Text = 'export .csv';
            app.exportxlsButton_2 = uibutton(app.COMPUTEEXPORTPanel, 'push');
            app.exportxlsButton_2.ButtonPushedFcn = createCallbackFcn(app, @exportxlsButton_2Pushed, true);
            app.exportxlsButton_2.Text = 'export .xls';
            app.ComputeSelectedSQIsButton = uibutton(app.COMPUTEEXPORTPanel, 'push');
            app.ComputeSelectedSQIsButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeSelectedSQIsButtonPushed, true);
            app.ComputeSelectedSQIsButton.Text = 'Compute Selected SQIs';
            app.FrequencyDomainBasedSQIsPanel = uipanel(app.SQIExtractionTab);
            app.FrequencyDomainBasedSQIsPanel.Title = 'Frequency Domain Based SQIs';
            app.SelectFrequencySQIsDropDownLabel = uilabel(app.FrequencyDomainBasedSQIsPanel);
            app.SelectFrequencySQIsDropDownLabel.Text = 'Select Frequency SQIs ';
            app.SelectFrequencySQIsDropDown = uidropdown(app.FrequencyDomainBasedSQIsPanel);
            app.SelectFrequencySQIsDropDown.Items = {'none', 'all', 'psdl', 'psdh', 'psdn', 'bassqi', 'iorsqi', 'psqi', 'lpsqi', 'mpsq', 'hpsqi'};
            app.SelectFrequencySQIsDropDown.Value = 'all';
            app.QRScomplexDetectorsbasedSQIsPanel = uipanel(app.SQIExtractionTab);
            app.QRScomplexDetectorsbasedSQIsPanel.Title = 'QRS-complex Detectors based SQIs';
            app.SelectQRSSQIsDropDownLabel = uilabel(app.QRScomplexDetectorsbasedSQIsPanel);
            app.SelectQRSSQIsDropDownLabel.Text = 'Select  QRS SQIs ';
            app.SelectQRSSQIsDropDown = uidropdown(app.QRScomplexDetectorsbasedSQIsPanel);
            app.SelectQRSSQIsDropDown.Items = {'none', 'all', 'tSQI', 'corSQI', 'iSQI', 'eSQI', 'pcaSQI'};
            app.SelectQRSSQIsDropDown.Value = 'all';
            % Show the figure after all components are created
            app.ecgScorerV10UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ecgScorer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ecgScorerV10UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ecgScorerV10UIFigure)
        end
    end
end